classdef mos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RadioButtonGroup     matlab.ui.container.ButtonGroup
        RadioButton_10       matlab.ui.control.RadioButton
        RadioButton_9        matlab.ui.control.RadioButton
        RadioButton_8        matlab.ui.control.RadioButton
        RadioButton_7        matlab.ui.control.RadioButton
        RadioButton_6        matlab.ui.control.RadioButton
        RadioButton_5        matlab.ui.control.RadioButton
        RadioButton_4        matlab.ui.control.RadioButton
        RadioButton_3        matlab.ui.control.RadioButton
        RadioButton_2        matlab.ui.control.RadioButton
        RadioButton_1        matlab.ui.control.RadioButton
        StartLabel           matlab.ui.control.Label
        StartButton          matlab.ui.control.Button
        CountTimelimitLabel  matlab.ui.control.Label
        DescriptionLabel_2   matlab.ui.control.Label
        RestartButton        matlab.ui.control.Button
        DescriptionLabel_1   matlab.ui.control.Label
        CountQuestionLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)       
        % audio device
        deviceWriter;
        audiodriver;
        audiodevice;

        % テスト
        Test;
        Grades;
        NumGrades;
        NumQuestionPerMethod;
        AnswerTime;
        CanRepeat;
        UseShufflePair;
        UseSameFile;
        UseReference;
        Reference;
        Methods;
        NumMethods;
           
        % 被験者識別ID
        id_table = [...
            'marzel',...
            'skiller',... 
            'ballzee',...
            'dharni',...
            'gene',...
            'kenny',...
            'napom',...
            'codfish',...
            'dlow',...
            'zer0',...
            'colaps',...
            'river'...
            'julard'...
            ];
        isIdDuplicated = false;
        
        % オーディオファイルのリスト
        audioLists;

        % 実験の開始・終了フラグ
        isStarted = false;
        isFinished = false;
        
        % 今何問目か
        currentQuestion = 1;

        % ウインドウサイズ
        windowWidth;
        windowHeight;
    end
    
    methods (Access = public)
        function collectAudio(app)
            % 対応するファイル拡張子のリスト
            extList = {'*.wav','*.mp3','*.opus'};
            
            % メソッド名（例："48k", "opus_16k", …）を取得
            methodNames = fieldnames(app.Methods);
            numMethods = length(methodNames);
            
            % リファレンスの読み込み（UseReference が true の場合）
            if app.UseReference
                % json で設定されたリファレンス用ディレクトリ（例: app.Reference）からファイルを収集
                refPath = app.Reference;
                refFiles = [];
                for e = 1:length(extList)
                    refFiles = [refFiles; dir(fullfile(refPath, extList{e}))];
                end
                refMap = containers.Map;
                refBases = {};
                for j = 1:numel(refFiles)
                    [~, baseName, ~] = fileparts(refFiles(j).name);
                    if ~isKey(refMap, baseName)
                        refMap(baseName) = fullfile(refFiles(j).folder, refFiles(j).name);
                        refBases{end+1} = baseName;
                    end
                end
            end
            
            questions = [];
            idx = 1;
            
            if app.UseSameFile
                % 各手法ディレクトリから，拡張子を除いたベースネームとファイルパスを取得
                allBases = cell(numMethods,1);
                fileMaps = cell(numMethods,1);
                for i = 1:numMethods
                    methodName = methodNames{i};
                    methodPath = app.Methods.(methodName);
                    audioFiles = [];
                    for e = 1:length(extList)
                        audioFiles = [audioFiles; dir(fullfile(methodPath, extList{e}))];
                    end
                    
                    map = containers.Map;
                    bases = {};
                    for j = 1:numel(audioFiles)
                        [~, baseName, ~] = fileparts(audioFiles(j).name);
                        if ~isKey(map, baseName)
                            map(baseName) = fullfile(audioFiles(j).folder, audioFiles(j).name);
                            bases{end+1} = baseName; %#ok<AGROW>
                        end
                    end
                    allBases{i} = bases;
                    fileMaps{i} = map;
                end
                
                % 全手法で共通するベースネームを抽出
                commonBases = allBases{1};
                for i = 2:numMethods
                    commonBases = intersect(commonBases, allBases{i});
                end
                
                % UseReference が true なら、リファレンスのベースネームとも共通である必要がある
                if app.UseReference
                    commonBases = intersect(commonBases, refBases);
                end
                
                % 各手法あたり選択する音声数
                numSelect = app.NumQuestionPerMethod;
                if numel(commonBases) < numSelect
                    error('全手法で共通する音声が不足しています．必要数: %d, 利用可能: %d', numSelect, numel(commonBases));
                end
                
                % ランダムに共通ベースを抽出
                perm = randperm(numel(commonBases), numSelect);
                selectedBases = commonBases(perm);
                
                % 選択されたベースネームについて各手法から対応するファイルを取得
                for k = 1:numel(selectedBases)
                    baseName = selectedBases{k};
                    for i = 1:numMethods
                        methodName = methodNames{i};
                        filePath = fileMaps{i}(baseName);
                        questions(idx).method = methodName;
                        questions(idx).baseName = baseName;
                        questions(idx).filePath = filePath;
                        questions(idx).Score = ceil(app.NumGrades/2);
                        % リファレンス用ファイルを追加
                        if app.UseReference
                            questions(idx).refFilePath = refMap(baseName);
                        end
                        idx = idx + 1;
                    end
                end
            % UseSameFile が false の場合：各手法から個別にランダム抽出
            else
                for i = 1:numMethods
                    methodName = methodNames{i};
                    methodPath = app.Methods.(methodName);
                    audioFiles = [];
                    for e = 1:length(extList)
                        audioFiles = [audioFiles; dir(fullfile(methodPath, extList{e}))]; %#ok<AGROW>
                    end
                    
                    numAvailable = numel(audioFiles);
                    if numAvailable < app.NumQuestionPerMethod
                        error('手法 %s の音声ファイルが不足しています．必要数: %d, 利用可能: %d', methodName, app.NumQuestionPerMethod, numAvailable);
                    end
                    
                    % 各手法でランダムに所定数抽出
                    perm = randperm(numAvailable, app.NumQuestionPerMethod);
                    for j = 1:length(perm)
                        fileInfo = audioFiles(perm(j));
                        [~, baseName, ~] = fileparts(fileInfo.name);
                        filePath = fullfile(fileInfo.folder, fileInfo.name);
                        % リファレンスが必要な場合、同じベースネームのファイルがリファレンスに存在するかチェック
                        if app.UseReference
                            if ~isKey(refMap, baseName)
                                error('リファレンスディレクトリに基準音声が存在しません: %s', baseName);
                            end
                        end
                        questions(idx).method = methodName;
                        questions(idx).baseName = baseName;
                        questions(idx).filePath = filePath;
                        questions(idx).Score = ceil(app.NumGrades/2);
                        if app.UseReference
                            questions(idx).refFilePath = refMap(baseName);
                        end
                        idx = idx + 1;
                    end
                end
            end
            
            % 全設問リストをランダムに並び替え
            permQuestions = randperm(numel(questions));
            questions = questions(permQuestions);
            
            % 設問リストを格納
            app.audioLists = questions;
            
            fprintf('合計 %d 件の設問が用意されました．\n', numel(app.audioLists));
        end

        function playAudio(app, audio_path)
            [waveform, fs] = audioread(audio_path);
            sound(waveform, fs);
            audio_shape = size(waveform);
            pause(audio_shape(1)/fs);
        end
        
        function disablePlayButton(app, btn)
            btn.Enable = "off";
            drawnow;
        end

        function enablePlayButton(app, btn)
            btn.Enable = "on";
            drawnow;
        end    
        
        function result = isDataImported(app)
            result = numel(app.method_dirs) > 0;
        end
        
        function startCountDown(app, countdownSeconds)            
            % カウントダウン用ラベルを表示
            app.CountTimelimitLabel.Visible = "on";
            
            % 指定秒数から 0 までループ
            for sec = countdownSeconds:-1:1
                % カウントダウンのメッセージを作成し、ラベルに設定
                app.CountTimelimitLabel.Text = sprintf('実験開始まであと %d 秒', sec);
                drawnow;   % UI更新を強制
                pause(1);  % 1秒待機
            end
        end
        
        function initializeRadioButton(app)
            app.RadioButtonGroup.BorderType = 'none';
            
            % ボタンの数
            numButtons = app.NumGrades;
            
            % ボタンの幅
            buttonWidth = 100;
            
            % 左右の余白
            leftMargin = 20;
            rightMargin = 20;
            
            % 利用可能な幅を計算
            available_width = app.windowWidth - leftMargin - rightMargin - (buttonWidth * numButtons);
            
            % ボタン間の間隔を計算（自動調整）
            radio_space = available_width / (numButtons + 1);
            
            for k = 1:numButtons
                button = app.("RadioButton_" + k);
                button.Text = string(app.Grades(k));
                button.Position = [leftMargin + radio_space*k + buttonWidth*(k-1), 70, buttonWidth, 22];
                button.Visible = "on";
                % 真ん中を初期値
                if k == ceil(numButtons/2)
                    button.Value = 1;
                else
                    button.Value = 0;
                end
            end
            for k = numButtons+1:10
                button = app.("RadioButton_" + k);
                button.Visible = "off";
            end
        end
        
        function readConfig(app, jsonfile)
            config = jsondecode(fileread(jsonfile));
            
            % 試験法の種類
            app.Test = config.Test;
            
            % 段階数とそれぞれの項目
            app.Grades = config.Grades;
            app.NumGrades = length(app.Grades);
            
            % 手法ごとのパス
            app.Reference = config.Reference;
            app.Methods = config.Methods;
            app.NumMethods = length(app.Methods);
            app.NumQuestionPerMethod = config.NumQuestionPerMethod;
            
            % 回答時間
            app.AnswerTime = config.AnswerTime;
            % リピート再生ありかどうか
            app.CanRepeat = config.CanRepeat;
            % リファレンスと刺激の提示順をランダムにするかどうか
            app.UseShufflePair = config.UseShufflePair;
            % 同じファイル
            app.UseSameFile = config.UseSameFile;
            app.UseReference = config.UseReference;
            
            % 説明
            app.DescriptionLabel_1.Text = '';
            for k = 1:length(config.ProcedureText)
                app.DescriptionLabel_1.Text = [app.DescriptionLabel_1.Text config.ProcedureText{k} newline];
            end
            app.DescriptionLabel_2.Text = '';
            for k = 1:length(config.CriteriaText)
                app.DescriptionLabel_2.Text = [app.DescriptionLabel_2.Text  config.CriteriaText{k}  newline];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % json 読み込み
            % [jsonfile, ~] = uigetfile({'mos.json'}, 'Select a json file','.');
            jsonfile = "./config/dmos.json";
            readConfig(app, jsonfile);
            
            % ウインドウサイズ
            app.windowWidth = app.UIFigure.Position(3);
            app.windowHeight = app.UIFigure.Position(4);
            
            % 初期変数割当
            if app.Test == "MOS"
                initializeRadioButton(app);
            elseif app.Test == "DMOS"
                initializeRadioButton(app);
            elseif app.Test == "MUSHRA"
                disp("Sorry, TODO");
            elseif app.Test == "AB"
                disp("Sorry, TODO");
            end
            
            % 設問と残り時間
            app.CountTimelimitLabel.Visible = "off";
            app.CountQuestionLabel.Visible = "off";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % 一度スタートしたら実験中はスタートボタンとラベルを非表示
            app.StartLabel.Visible = "off";
            app.StartButton.Visible = "off";
            
            % ref_paths と Methods にオーディオのパスを格納
            collectAudio(app);
            %aaa = questions(1).filePath;
            
            % 実験開始までのカウントダウン(3秒)
            startCountDown(app, 3);

            numQuestions = numel(app.audioLists);
            for q = 1:numQuestions
                % 現在の設問番号を更新
                app.currentQuestion = q;
                app.CountQuestionLabel.Text = sprintf('現在 %d 問目（%d問中）', q, numQuestions);
                app.CountQuestionLabel.Visible = "on";
                
                % UseReference が有効の場合は、リファレンス音声を先に再生
                if app.UseReference
                    app.CountTimelimitLabel.Text = "1番目の音声を再生中...";
                    app.playAudio(app.audioLists(q).refFilePath);
                    % リファレンス再生後、少し間を置く（例：0.5秒）
                    pause(0.5);
                end
                
                % 刺激音声（評価対象）の再生
                if app.UseReference
                    app.CountTimelimitLabel.Text = "2番目の音声を再生中...";
                else
                    app.CountTimelimitLabel.Text = "評価音声を再生中...";
                end
                app.playAudio(app.audioLists(q).filePath);
                
                % 回答受付（回答時間内にユーザがラジオボタンで評価を選択）
                startTime = tic;
                while toc(startTime) < app.AnswerTime
                    remaining = ceil(app.AnswerTime - toc(startTime));
                    app.CountTimelimitLabel.Text = sprintf('回答してください（次の設問まで %d 秒）', remaining);
                    drawnow;  % UI更新を強制
                    pause(0.1);
                end
                
                % 回答時間経過後、現在のラジオボタンの選択状態を評価値として取得
                selectedObj = app.RadioButtonGroup.SelectedObject;
                rating = str2double(selectedObj.Text);
                app.audioLists(q).Score = rating;
            end
            
            AudioList = app.audioLists;
            
            % 保存先ディレクトリを指定（なければ作成）
            saveDir = './results';
            if ~exist(saveDir, 'dir')
                mkdir(saveDir);
            end
            
            % タイムスタンプを付与してファイル名を作成
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            filename = fullfile(saveDir, [app.Test '_' timestamp '.mat']);
            
            % AudioList を .mat ファイルとして保存
            save(filename, 'AudioList');
            fprintf('評価結果が %s に保存されました．\n', filename);

            app.CountTimelimitLabel.Text = "実験は終了です．お疲れ様でした．"
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            collectAudio(app);
            app.CountQuestionLabel.Visible = "off";
            app.StartButton.Visible = "on";
            app.NumQField.Visible = "on";
            app.NumQLabel.Visible = "on";
            app.MethodButtonGroup = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CountQuestionLabel = uilabel(app.UIFigure);
            app.CountQuestionLabel.Text = '現在 XX問目（YY問中）';
            app.DescriptionLabel_1 = uilabel(app.UIFigure);
            app.DescriptionLabel_1.Text = {'人間の発話を収録したものとして音質劣化や違和感がなく，'; 'どのくらい自然であるかを評価してください．'};
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.DescriptionLabel_2 = uilabel(app.UIFigure);
            app.DescriptionLabel_2.Text = {'1問ごとに音声が2つ連続して流れます．'; '1番目と比較して2番目の音声がどのくらいの品質かを評価してください．'};
            app.CountTimelimitLabel = uilabel(app.UIFigure);
            app.CountTimelimitLabel.Text = 'あとXX秒以内に回答してください．';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = '実験の準備ができたら Start ボタンを押してください．';
            app.RadioButtonGroup = uibuttongroup(app.UIFigure);
            app.RadioButton_1 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_1.Text = '1';
            app.RadioButton_1.Value = true;
            app.RadioButton_2 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_2.Text = '2';
            app.RadioButton_3 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_3.Text = '3';
            app.RadioButton_4 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_4.Text = '4';
            app.RadioButton_5 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_5.Text = '5';
            app.RadioButton_6 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_6.Text = '6';
            app.RadioButton_7 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_7.Text = '7';
            app.RadioButton_8 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_8.Text = '8';
            app.RadioButton_9 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_9.Text = '9';
            app.RadioButton_10 = uiradiobutton(app.RadioButtonGroup);
            app.RadioButton_10.Text = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end