classdef mushra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        fileMenu            matlab.ui.container.Menu
        importMenu          matlab.ui.container.Menu
        exportMenu          matlab.ui.container.Menu
        configMenu          matlab.ui.container.Menu
        audioMenu           matlab.ui.container.Menu
        RestartButton       matlab.ui.control.Button
        NumQField           matlab.ui.control.NumericEditField
        NumQLabel           matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        DescriptionLabel    matlab.ui.control.Label
        EnDescriptionLabel  matlab.ui.control.Label
        CountLabel          matlab.ui.control.Label
        RefLabel            matlab.ui.control.Label
        RefButton           matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
        PlayButton_1        matlab.ui.control.Button
        PlayButton_2        matlab.ui.control.Button
        PlayButton_3        matlab.ui.control.Button
        PlayButton_4        matlab.ui.control.Button
        PlayButton_5        matlab.ui.control.Button
        Slider_1            matlab.ui.control.Slider
        Slider_2            matlab.ui.control.Slider
        Slider_3            matlab.ui.control.Slider
        Slider_4            matlab.ui.control.Slider
        Slider_5            matlab.ui.control.Slider
        Label_1             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
    end

    
    properties (Access = public)
        num_questions = 30;
        current_question = 1;
        subject_id = 'default';
        id_table = ['a', 'b', 'c', 'd', 'e', 'f', 'g']
        is_imported = false;
        audio_dir = './audio';
        csv_dir = './csv';
        ref_paths;
        method_dirs;
        audio_lists;
        question_indices;
        assigned_methods;
        scores;

        deviceWriter;
        audiodriver;
        audiodevice;
    end
    
    methods (Access = public)
        function collectAudio(app)
            audio_dir = app.audio_dir;
            num_questions = app.num_questions;
            % % サブディレクトリの取得
            method_dirs = dir(audio_dir);
            method_dirs = method_dirs([method_dirs.isdir]);
            method_dirs = method_dirs(~ismember({method_dirs.name}, {'.', '..', 'ref'})); % 'ref'ディレクトリを除外
            app.method_dirs = method_dirs;
            
            % 各フォルダからファイル名を取得し、ファイルパスを格納
            audio_lists = cell(numel(method_dirs), 1);
            ref_paths = {}; % 空のセル配列で初期化
            
            for i = 1:numel(method_dirs)
                files = dir(fullfile(audio_dir, method_dirs(i).name, '*'));
                files = files(~[files.isdir]);
                audio_lists{i} = fullfile(audio_dir, method_dirs(i).name, {files.name});
                
                ref_files = dir(fullfile(audio_dir, 'ref', '*'));
                ref_files = ref_files(~[ref_files.isdir]);
                ref_paths = fullfile(audio_dir, 'ref', {ref_files.name});
            end
            
            app.audio_lists = audio_lists;
            app.ref_paths = ref_paths;
            app.question_indices = randsample(numel(app.audio_lists{1}), num_questions);
        end

        function playAudio(app, audio_path)
            disp(audio_path);
            [waveform, fs] = audioread(audio_path);
            sound(waveform, fs);
            audio_shape = size(waveform);
            pause(audio_shape(1)/fs);
        end
        
        function disablePlayButton(app)
            app.RefButton.Enable = "off";
            app.PlayButton_1.Enable = "off";
            app.PlayButton_2.Enable = "off";
            app.PlayButton_3.Enable = "off";
            app.PlayButton_4.Enable = "off";
            app.PlayButton_5.Enable = "off";
            drawnow;
        end

        function enablePlayButton(app)
            app.RefButton.Enable = "on";
            app.PlayButton_1.Enable = "on";
            app.PlayButton_2.Enable = "on";
            app.PlayButton_3.Enable = "on";
            app.PlayButton_4.Enable = "on";
            app.PlayButton_5.Enable = "on";
            drawnow;
        end
        
        function PlayButtonPushed(app, button_index)
            disablePlayButton(app);
            question_index = app.question_indices(app.current_question);
            if button_index
                assigned_methods = app.assigned_methods;
                audio_lists = app.audio_lists;

                assigned_method = assigned_methods(app.current_question, button_index);
                audio_list = app.audio_lists{assigned_method};
                audio_path = audio_list{question_index};
            else
                ref_paths = app.ref_paths;
                audio_path = ref_paths{question_index};
            end

            playAudio(app, audio_path);
            enablePlayButton(app);
        end
        
        function SliderValueChanged(app, value, slider_index)
            temp_scores = app.scores;
            assigned_methods = app.assigned_methods;
            assigned_method = assigned_methods(app.current_question, slider_index);
            temp_scores(app.current_question, assigned_method) = round(value);
            app.scores = temp_scores;
            app.scores
        end
        
        function saveMatrix(app)
            size(string({app.method_dirs.name}))
            size(app.scores)
            matrix = [string({app.method_dirs.name}); app.scores];
            csv_path = fullfile(app.csv_dir, app.subject_id+'.csv');
            writematrix(matrix, csv_path);
        end
        
        function result = isDataImported(app)
            result = numel(app.method_dirs) > 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumQField.Value = app.num_questions;
            collectAudio(app)

            app.CountLabel.Visible = "off";
            app.RefLabel.Visible = "off";
            app.RefButton.Visible = "off";
            app.NextButton.Visible = "off";
            app.Label_1.Visible = "off";
            app.Label_2.Visible = "off";
            app.Label_3.Visible = "off";
            app.Label_4.Visible = "off";
            app.Label_5.Visible = "off";
            app.PlayButton_1.Visible = "off";
            app.PlayButton_2.Visible = "off";
            app.PlayButton_3.Visible = "off";
            app.PlayButton_4.Visible = "off";
            app.PlayButton_5.Visible = "off";
            app.Slider_1.Visible = "off";
            app.Slider_2.Visible = "off";
            app.Slider_3.Visible = "off";
            app.Slider_4.Visible = "off";
            app.Slider_5.Visible = "off";
            app.RestartButton.Visible = "off";
        end

        % Menu selected function: importMenu
        function importMenuSelected(app, event)
            app.audio_dir = uigetdir('./');
            collectAudio(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isDataImported(app)
                app.num_questions = app.NumQField.Value;
                app.CountLabel.Text = sprintf("現在 %d問目（%d問中）", app.current_question, app.num_questions);
                drawnow;
                
                app.subject_id = app.id_table(randi(numel(app.id_table)))+string(datetime("now", Format='yyyyMMddHHmm'));
                assigned_methods = zeros(app.num_questions, numel(app.method_dirs));
                app.scores = zeros(app.num_questions, numel(app.method_dirs));
                size(assigned_methods)
                app.scores
                num_methods = numel(app.method_dirs);
                for i = 1:app.num_questions
                    assigned_methods(i, :) = randperm(num_methods);
                end
                app.assigned_methods = assigned_methods;

                app.StartButton.Visible = "off";
                app.NumQField.Visible = "off";
                app.NumQLabel.Visible = "off";
                app.CountLabel.Visible = "on";
                app.RefLabel.Visible = "on";
                app.RefButton.Visible = "on";
                app.NextButton.Visible = "on";
                app.Label_1.Visible = "on";
                app.Label_2.Visible = "on";
                app.Label_3.Visible = "on";
                app.Label_4.Visible = "on";
                app.Label_5.Visible = "on";
                app.PlayButton_1.Visible = "on";
                app.PlayButton_2.Visible = "on";
                app.PlayButton_3.Visible = "on";
                app.PlayButton_4.Visible = "on";
                app.PlayButton_5.Visible = "on";
                app.Slider_1.Visible = "on";
                app.Slider_2.Visible = "on";
                app.Slider_3.Visible = "on";
                app.Slider_4.Visible = "on";
                app.Slider_5.Visible = "on";
            elseif app.NumQField.Value < 0 || mod(app.NumQField.Value, 1) ~= 0
                msgbox(['設問数は正の整数で与えてください。' newline 'The number of question must be an integer.']);
            else
                msgbox(['音声ファイルが読み込まれていません。' newline 'Audio files are not imported.']);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.current_question == app.num_questions
                app.RefLabel.Visible = "off";
                app.RefButton.Visible = "off";
                app.NextButton.Visible = "off";
                app.Label_1.Visible = "off";
                app.Label_2.Visible = "off";
                app.Label_3.Visible = "off";
                app.Label_4.Visible = "off";
                app.Label_5.Visible = "off";
                app.PlayButton_1.Visible = "off";
                app.PlayButton_2.Visible = "off";
                app.PlayButton_3.Visible = "off";
                app.PlayButton_4.Visible = "off";
                app.PlayButton_5.Visible = "off";
                app.Slider_1.Visible = "off";
                app.Slider_2.Visible = "off";
                app.Slider_3.Visible = "off";
                app.Slider_4.Visible = "off";
                app.Slider_5.Visible = "off";
                app.CountLabel.Text = "実験は終了です。ご協力ありがとうございました"
                app.RestartButton.Visible = "on";

                saveMatrix(app)
            
            else
                app.current_question = app.current_question + 1;
                app.CountLabel.Text = sprintf("現在 %d問目（%d問中）", app.current_question, app.num_questions);
                app.Slider_1.Value = 0;
                app.Slider_2.Value = 0;
                app.Slider_3.Value = 0;
                app.Slider_4.Value = 0;
                app.Slider_5.Value = 0;
             
                if app.current_question == app.num_questions
                    app.NextButton.Text = 'Submit'
                    app.NextButton.BackgroundColor = [0.30,0.75,0.93];
                end
              
            end
            drawnow;
        end

        % Button pushed function: PlayButton_1
        function PlayButton_1Pushed(app, event)
            button_index = 1;
            PlayButtonPushed(app, button_index);
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            button_index = 2;
            PlayButtonPushed(app, button_index);
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            button_index = 3;
            PlayButtonPushed(app, button_index);
        end

        % Button pushed function: PlayButton_4
        function PlayButton_4Pushed(app, event)
            button_index = 4;
            PlayButtonPushed(app, button_index);
        end

        % Button pushed function: PlayButton_5
        function PlayButton_5Pushed(app, event)
            button_index = 5;
            PlayButtonPushed(app, button_index);
        end

        % Button pushed function: RefButton
        function RefButtonPushed(app, event)
            button_index = 0;
            PlayButtonPushed(app, button_index);
        end

        % Value changed function: Slider_1
        function Slider_1ValueChanged(app, event)
            value = app.Slider_1.Value;
            slider_index = 1;
            SliderValueChanged(app, value, slider_index);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            slider_index = 2
            SliderValueChanged(app, value, slider_index);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            slider_index = 3;
            SliderValueChanged(app, value, slider_index);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            slider_index = 4;
            SliderValueChanged(app, value, slider_index);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            slider_index = 5;
            SliderValueChanged(app, value, slider_index);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            collectAudio(app);
            app.CountLabel.Visible = "off";
            app.StartButton.Visible = "on";
            app.NumQField.Visible = "on";
            app.NumQLabel.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fileMenu = uimenu(app.UIFigure);
            app.fileMenu.Text = 'ファイル';
            app.importMenu = uimenu(app.fileMenu);
            app.importMenu.MenuSelectedFcn = createCallbackFcn(app, @importMenuSelected, true);
            app.importMenu.Text = 'インポート';
            app.exportMenu = uimenu(app.fileMenu);
            app.exportMenu.Text = 'エクスポート';
            app.configMenu = uimenu(app.UIFigure);
            app.configMenu.Text = '環境設定';
            app.audioMenu = uimenu(app.configMenu);
            app.audioMenu.Text = 'オーディオ';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '⑤';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '④';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '③';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '②';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = '①';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_5.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.MinorTicks = [];
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_4.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_3.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_2.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_1 = uislider(app.UIFigure);
            app.Slider_1.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.PlayButton_5 = uibutton(app.UIFigure, 'push');
            app.PlayButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_5Pushed, true);
            app.PlayButton_5.Text = 'Play';
            app.PlayButton_4 = uibutton(app.UIFigure, 'push');
            app.PlayButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_4Pushed, true);
            app.PlayButton_4.Text = 'Play';
            app.PlayButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PlayButton_1 = uibutton(app.UIFigure, 'push');
            app.PlayButton_1.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_1Pushed, true);
            app.PlayButton_1.Text = 'Play';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.RefButton = uibutton(app.UIFigure, 'push');
            app.RefButton.ButtonPushedFcn = createCallbackFcn(app, @RefButtonPushed, true);
            app.RefButton.Text = 'Play';
            app.RefLabel = uilabel(app.UIFigure);
            app.RefLabel.Text = {'この音声を基準（100）として評価してください'; 'Rate as this is a reference (100)'};
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = '現在 XX問目（YY問中）';
            app.EnDescriptionLabel = uilabel(app.UIFigure);
            app.EnDescriptionLabel.Text = {'Listen to the audio below and rate how "natural" they are, without any artificial noise or discomfort,'; 'considering they are a recording of human speech.'};
            app.DescriptionLabel = uilabel(app.UIFigure);
            app.DescriptionLabel.Text = {'以下の音声を聴いて，人間の発話を収録したものとして音質劣化や違和感がなく，'; 'どのくらい高品質であるかを評価してください'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NumQLabel = uilabel(app.UIFigure);
            app.NumQLabel.Text = '設問数';
            app.NumQField = uieditfield(app.UIFigure, 'numeric');
            app.NumQField.Limits = [0 Inf];
            app.NumQField.RoundFractionalValues = 'on';
            app.NumQField.ValueDisplayFormat = '%.0f';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mushra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end